classdef BNeumatico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BombeoNeumticoPanel          matlab.ui.container.Panel
        DatosDelProblemaPanel        matlab.ui.container.Panel
        qoEditFieldLabel             matlab.ui.control.Label
        qoEditField                  matlab.ui.control.NumericEditField
        PwhEditFieldLabel            matlab.ui.control.Label
        PwhEditField                 matlab.ui.control.NumericEditField
        ginyEditFieldLabel           matlab.ui.control.Label
        ginyEditField                matlab.ui.control.NumericEditField
        TREditFieldLabel             matlab.ui.control.Label
        TREditField                  matlab.ui.control.NumericEditField
        TPEditFieldLabel             matlab.ui.control.Label
        TPEditField                  matlab.ui.control.NumericEditField
        IPLabel                      matlab.ui.control.Label
        IPEditField                  matlab.ui.control.NumericEditField
        bpdLabel                     matlab.ui.control.Label
        psiLabel                     matlab.ui.control.Label
        psiLabel_2                   matlab.ui.control.Label
        psiLabel_3                   matlab.ui.control.Label
        adimLabel                    matlab.ui.control.Label
        FCLabel_2                    matlab.ui.control.Label
        FCEditField                  matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        pulgLabel                    matlab.ui.control.Label
        pulgLabel_2                  matlab.ui.control.Label
        bpdpsiLabel                  matlab.ui.control.Label
        IniciarButton                matlab.ui.control.Button
        piesLabel                    matlab.ui.control.Label
        ProfEmpEditFieldLabel        matlab.ui.control.Label
        ProfEmpEditField             matlab.ui.control.NumericEditField
        FLabel                       matlab.ui.control.Label
        TwhEditFieldLabel            matlab.ui.control.Label
        TwhEditField                 matlab.ui.control.NumericEditField
        FLabel_2                     matlab.ui.control.Label
        TfondoEditFieldLabel         matlab.ui.control.Label
        TfondoEditField              matlab.ui.control.NumericEditField
        PvalopEditFieldLabel         matlab.ui.control.Label
        PvalopEditField              matlab.ui.control.NumericEditField
        psiLabel_4                   matlab.ui.control.Label
        FLabel_3                     matlab.ui.control.Label
        TCTallerEditFieldLabel       matlab.ui.control.Label
        TCTallerEditField            matlab.ui.control.NumericEditField
        pie3blLabel                  matlab.ui.control.Label
        RGANEditFieldLabel           matlab.ui.control.Label
        RGANEditField                matlab.ui.control.NumericEditField
        pie3blLabel_2                matlab.ui.control.Label
        RGATEditFieldLabel           matlab.ui.control.Label
        RGATEditField                matlab.ui.control.NumericEditField
        APILabel                     matlab.ui.control.Label
        oilEditFieldLabel            matlab.ui.control.Label
        oilEditField                 matlab.ui.control.NumericEditField
        psiLabel_5                   matlab.ui.control.Label
        PwsEditFieldLabel            matlab.ui.control.Label
        PwsEditField                 matlab.ui.control.NumericEditField
        AbEditFieldLabel             matlab.ui.control.Label
        AbEditField                  matlab.ui.control.NumericEditField
        adimLabel_4                  matlab.ui.control.Label
        PsoEditFieldLabel            matlab.ui.control.Label
        PsoEditField                 matlab.ui.control.NumericEditField
        PdiEditFieldLabel            matlab.ui.control.Label
        PdiEditField                 matlab.ui.control.NumericEditField
        adimLabel_5                  matlab.ui.control.Label
        AvEditFieldLabel             matlab.ui.control.Label
        AvEditField                  matlab.ui.control.NumericEditField
        adimLabel_6                  matlab.ui.control.Label
        AguaEditFieldLabel           matlab.ui.control.Label
        AguaEditField                matlab.ui.control.NumericEditField
        Panel2                       matlab.ui.container.Panel
        psiLabel_6                   matlab.ui.control.Label
        PwfEditFieldLabel            matlab.ui.control.Label
        PwfEditField                 matlab.ui.control.EditField
        APILabel_2                   matlab.ui.control.Label
        APILabel_3                   matlab.ui.control.Label
        APILabel_4                   matlab.ui.control.Label
        APILabel_5                   matlab.ui.control.Label
        APILabel_6                   matlab.ui.control.Label
        APILabel_7                   matlab.ui.control.Label
        APILabel_8                   matlab.ui.control.Label
        APILabel_9                   matlab.ui.control.Label
        adimLabel_2                  matlab.ui.control.Label
        GradMEditFieldLabel          matlab.ui.control.Label
        GradMEditField               matlab.ui.control.EditField
        ftLabel                      matlab.ui.control.Label
        NDEditFieldLabel             matlab.ui.control.Label
        NDEditField                  matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
        Panel3                       matlab.ui.container.Panel
        psi1000ftLabel               matlab.ui.control.Label
        pleidaEditFieldLabel         matlab.ui.control.Label
        pleidaEditField              matlab.ui.control.EditField
        FLabel_4                     matlab.ui.control.Label
        TCalcEditFieldLabel          matlab.ui.control.Label
        TCalcEditField               matlab.ui.control.EditField
        FLabel_5                     matlab.ui.control.Label
        TpromEditFieldLabel          matlab.ui.control.Label
        TpromEditField               matlab.ui.control.EditField
        psi1000ftLabel_2             matlab.ui.control.Label
        pcorreEditFieldLabel         matlab.ui.control.Label
        pcorreEditField              matlab.ui.control.EditField
        psi1000ftLabel_3             matlab.ui.control.Label
        pleida2EditFieldLabel        matlab.ui.control.Label
        pleida2EditField             matlab.ui.control.EditField
        psi1000ftLabel_4             matlab.ui.control.Label
        pcorre2EditFieldLabel        matlab.ui.control.Label
        pcorre2EditField             matlab.ui.control.EditField
        GG10Label                    matlab.ui.control.Label
        GGGasGravityLabel            matlab.ui.control.Label
        GG09Label                    matlab.ui.control.Label
        GG08Label                    matlab.ui.control.Label
        GG07Label                    matlab.ui.control.Label
        GG065Label                   matlab.ui.control.Label
        GG06Label                    matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        LneasDeGradientePanel        matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        CalibracinDeVlvulasPanel     matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        ResultadosDeLasGrficasPanel  matlab.ui.container.Panel
        UITable2                     matlab.ui.control.Table
        UITable2_3                   matlab.ui.control.Table
        Panel                        matlab.ui.container.Panel
        LneadelgradienteDelFluidoDeControlPanel  matlab.ui.container.Panel
        psiftLabel                   matlab.ui.control.Label
        grafFCEditFieldLabel         matlab.ui.control.Label
        grafFCEditField              matlab.ui.control.EditField
        UITable3                     matlab.ui.control.Table
        LosvaloresseencuentranenelgrficoconunaxLabel  matlab.ui.control.Label
        BombeoNeumticoLabel          matlab.ui.control.Label
        CalculodelGastodelGasPanel   matlab.ui.container.Panel
        UITable2_4                   matlab.ui.control.Table
        FLabel_6                     matlab.ui.control.Label
        FLabel_7                     matlab.ui.control.Label
        FLabel_8                     matlab.ui.control.Label
        FLabel_9                     matlab.ui.control.Label
        FLabel_10                    matlab.ui.control.Label
        pie3diaLabel                 matlab.ui.control.Label
        qgiEditFieldLabel            matlab.ui.control.Label
        qgiEditField                 matlab.ui.control.EditField
        GastodegasdeinyeccinLabel    matlab.ui.control.Label
        FactordecorrecindelgastodegasdeinyecLabel  matlab.ui.control.Label
        adimLabel_3                  matlab.ui.control.Label
        FCLabel                      matlab.ui.control.Label
        FCEditField_2                matlab.ui.control.EditField
        GastodegasdeinyeccincorregidoLabel  matlab.ui.control.Label
        pie3diaLabel_2               matlab.ui.control.Label
        qgicEditFieldLabel           matlab.ui.control.Label
        qgicEditField                matlab.ui.control.EditField
        FLabel_11                    matlab.ui.control.Label
        TpiEditFieldLabel            matlab.ui.control.Label
        TpiEditField                 matlab.ui.control.EditField
        kEditFieldLabel              matlab.ui.control.Label
        kEditField                   matlab.ui.control.EditField
        CdAEditFieldLabel            matlab.ui.control.Label
        CdAEditField                 matlab.ui.control.EditField
        DiametroEditFieldLabel       matlab.ui.control.Label
        DiametroEditField            matlab.ui.control.EditField
        pgLabel                      matlab.ui.control.Label
        REditFieldLabel              matlab.ui.control.Label
        REditField                   matlab.ui.control.EditField
        DatosRelaciondeCaloresEspecficosLabel  matlab.ui.control.Label
        adimLabel_7                  matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            Pws = app.PwsEditField.Value;
            qo = app.qoEditField.Value;
            IP = app.IPEditField.Value;
            Denoil = app.oilEditField.Value;
            x = app.AguaEditField.Value; %%% porcentaje de Agua 
            dg = app.ginyEditField.Value;
            xx = app.PsoEditField.Value; %%Pso
            Pdisp = app.PdiEditField.Value;
            ProfE = app.ProfEmpEditField.Value;
            Pwh = app.PwhEditField.Value;
            Tcabeza=app.TwhEditField.Value;
            Tfondo = app.TfondoEditField.Value;
            gfc=app.FCEditField.Value;
            
            
            %Determinar pwf
            
            Pwf = Pws - (qo/IP);
            app.PwfEditField.Value=num2str(Pwf);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%Calcular nivel dinámico y gradiente fluyendo abajo del punto de inyección%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%Primera Grafica%%%%%%%%%%%%%%%%%
            
            hold(app.UIAxes,'on'); %%Grafica mas de una sola gafica 
            
            %10° API
            xx1 = [0 20 40 60 80 100];
            yy1 = [0.4306 0.4375 0.4445 0.451 0.4575 0.464  ];
            plot(app.UIAxes,xx1,yy1,'b')
            
            %15° API
            xx2 = [0 20 40 60 80 100];
            yy2 = [ 0.4167 0.4265 0.436 0.4455 0.455 0.464 ];
            plot(app.UIAxes,xx2,yy2,'Color', [ 0.85 0.33 0.10 ])
            
            %20° API
            xx2 = [0 20 40 60 80 100];
            yy2 = [ 0.404 0.416 0.428 0.44 0.452 0.464 ];
            plot(app.UIAxes,xx2,yy2,'Color', [ 0.93 0.69 0.13 ])
            
            %25° API
            x3 = [0 20 40 60 80 100];
            y3 = [0.3913 0.406 0.4205 0.435 0.4499 0.464 ];
            plot(app.UIAxes,x3,y3,'Color', [ 1.00 0.41 0.16 ])
            
            %30° API
            x4 = [0 20 40 60 80 100];
            y4 = [0.379 0.396 0.413 0.43 0.447 0.464 ];
            plot(app.UIAxes,x4,y4,'Color', [0.39 0.83 0.07 ])
            
            %35° API
            x5 = [0 20 40 60 80 100];
            y5 = [0.368 0.387 0.406 0.425 0.445 0.464 ];
            plot(app.UIAxes,x5,y5,'Color', [1.00 0.07 0.65 ])
            
            %40° API
            x6 = [0 20 40 60 80 100];
            y6 = [0.358 0.3787 0.4 0.4212 0.443  0.464 ];
            plot(app.UIAxes,x6,y6,'Color', [0.15 0.96 0.89 ])
            
            %45° API
            x7 = [0 20 40 60 80 100];
            y7 = [0.348 0.3706 0.3941 0.4175 0.4405  0.464 ];
            plot(app.UIAxes,x7,y7,'Color', [0.74 0.00 0.48 ])
            
            if Denoil==45
                y = 0.0012*x + 0.3474;
            elseif (Denoil<45) && (Denoil>=44)
                y = 0.0012*x + 0.3494;
            elseif (Denoil<44) && (Denoil>=43)
                y = 0.0012*x + 0.3514;
            elseif (Denoil<43) && (Denoil>=42)
                y = 0.0011*x + 0.3533;
            elseif (Denoil<42) && (Denoil>=41)
                y = 0.0011*x + 0.3553;
            elseif (Denoil<41) && (Denoil>=40)
                y = 0.0011*x + 0.3573;
            elseif (Denoil<40) && (Denoil>=39)
                y = 0.0011*x + 0.3594;
            elseif (Denoil<39) && (Denoil>=38)
                y = 0.0011*x + 0.3615;
            elseif (Denoil<38) && (Denoil>=37)
                y = 0.001*x + 0.3636;
            elseif (Denoil<37) && (Denoil>=36)
                y = 0.001*x + 0.3657;
            elseif (Denoil<36) && (Denoil>=35)
                y = 0.001*x + 0.3678;
            elseif (Denoil<35) && (Denoil>=34)
                y = 0.001*x + 0.37;
            elseif (Denoil<34) && (Denoil>=33)
                y = 0.0009*x + 0.3723;
            elseif (Denoil<33) && (Denoil>=32)
                y = 0.0009*x + 0.3745;
            elseif (Denoil<32) && (Denoil>=31)
                y = 0.0008*x + 0.3768;
            elseif (Denoil<31) && (Denoil>=30)
                y = 0.0008*x + 0.379;
            elseif (Denoil<30) && (Denoil>=29)
                y = 0.0008*x + 0.3815;
            elseif (Denoil<29) && (Denoil>=28)
                y = 0.0008*x + 0.384;
            elseif (Denoil<28) && (Denoil>=27)
                y = 0.0007*x + 0.3864;
            elseif (Denoil<27) && (Denoil>=26)
                y = 0.0007*x + 0.3889;
            elseif (Denoil<26) && (Denoil>=25)
                y = 0.0007*x + 0.3914;
            elseif (Denoil<25) && (Denoil>=24)
                y = 0.0007*x + 0.3939;
            elseif (Denoil<24) && (Denoil>=23)
                y = 0.0007*x + 0.3964;
            elseif (Denoil<23) && (Denoil>=22)
                y = 0.0006*x + 0.399;
            elseif (Denoil<22) && (Denoil>=21)
                y = 0.0006*x + 0.4015;
            elseif (Denoil<21) && (Denoil>=20)
                y = 0.0006*x + 0.404;
            elseif (Denoil<20) && (Denoil>=19)
                y = 0.0007*x + 0.3939;
            elseif (Denoil<19) && (Denoil>=18)
                y = 0.0007*x + 0.3964;
            elseif (Denoil<18) && (Denoil>=17)
                y = 0.0006*x + 0.399;
            elseif (Denoil<17) && (Denoil>=16)
                y = 0.0006*x + 0.4015;
            elseif (Denoil<16) && (Denoil>=15)
                y = 0.0005*x + 0.4169;
            elseif (Denoil<15) && (Denoil>=14)
                y = 0.0005*x + 0.4197;
            elseif (Denoil<14) && (Denoil>=13)
                y = 0.0004*x + 0.4225;
            elseif (Denoil<13) && (Denoil>=12)
                y = 0.0004*x + 0.4252;
            elseif (Denoil<12) && (Denoil>=11)
                y = 0.0003*x + 0.428;
            elseif (Denoil<11) && (Denoil>=10)
                y = 0.0003*x + 0.4308;
            end
            
            app.GradMEditField.Value=num2str(y);
            plot(app.UIAxes,x,y,'k*')
            
            ND=app.ProfEmpEditField.Value-(Pwf/y);
            app.NDEditField.Value=num2str(ND);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%Calculo del gradiente de gas a la presion de operacion y la presion disponible%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%% Segunda Gráfica %%%%%%%%%%%%%%
            
            hold(app.UIAxes_2,'on');
            
            %Gravedad del Aceite 1.0
            ax1 = [44.7 33.3 22 6.7 ];
            by1 = [ 1200 900 600 200 ];
            plot(app.UIAxes_2,ax1,by1,'Color', [ 0.00 0.45 0.74])
            
            %Gravedad del Aceite 0.9
            ax2 = [40 30 19.9 6.5 ];
            by2 = [ 1200 900 600 200 ];
            plot(app.UIAxes_2,ax2,by2,'Color', [ 0.85 0.33 0.10])
            
            %Gravedad del Aceite 0.8
            ax3 = [35.7 26.9 17.9 6 ];
            by3 = [ 1200 900 600 200 ];
            plot(app.UIAxes_2,ax3,by3,'Color', [ 0.39 0.83 0.07])
            
            %Gravedad del Aceite 0.7
            ax4 = [31.2 23.7 15.9 5.5];
            by4 = [ 1200 900 600 200 ];
            plot(app.UIAxes_2,ax4,by4,'Color', [ 0.74 0.00 0.48])
            
            %Gravedad del Aceite 0.65
            ax5 = [28 21.1 14.1 4.7 ];
            by5 = [ 1200 900 600 200 ];
            plot(app.UIAxes_2,ax5,by5,'Color', [ 1.00 0.41 0.16])
            
            %Gravedad del Aceite 0.6
            ax6 = [25 19 12.8 4.5 ];
            by6 = [ 1200 900 600 200 ];
            plot(app.UIAxes_2,ax6,by6,'Color', [ 0.21 0.79 0.74])
            
            if dg == 0.6
                yy = 0.0205*xx + 0.4443;
            elseif (dg>0.6) && (dg<=0.61)
                yy = 0.0211*xx + 0.3704;
            elseif (dg>0.61) && (dg<=0.62)
                yy = 0.0216*xx + 0.2965;
            elseif (dg>0.62) && (dg<=0.63)
                yy = 0.0222*xx + 0.2227;
            elseif (dg>0.63) && (dg<=0.64)
                yy = 0.0227*xx + 0.1488;
            elseif (dg>0.64) && (dg<=0.65)
                yy = 0.0233*xx + 0.0749;
            elseif (dg>0.65) && (dg<=0.66)
                yy = 0.0238*xx + 0.1427;
            elseif (dg>0.66) && (dg<=0.67)
                yy = 0.0243*xx + 0.2104;
            elseif (dg>0.67) && (dg<=0.68)
                yy = 0.0247*xx + 0.2782;
            elseif (dg>0.68) && (dg<=0.69)
                yy = 0.0252*xx + 0.3459;
            elseif (dg>0.69) && (dg<=0.7)
                yy = 0.0257*xx + 0.4137;
            elseif (dg>0.7) && (dg<=0.71)
                yy = 0.0261*xx + 0.3794;
            elseif (dg>0.71) && (dg<=0.72)
                yy = 0.0265*xx + 0.345;
            elseif (dg>0.72) && (dg<=0.73)
                yy = 0.0269*xx + 0.3107;
            elseif (dg>0.73) && (dg<=0.74)
                yy = 0.0273*xx + 0.2763;
            elseif (dg>0.74) && (dg<=0.75)
                yy = 0.0277*xx + 0.242;
            elseif (dg>0.75) && (dg<=0.76)
                yy = 0.0281*xx + 0.2077;
            elseif (dg>0.76) && (dg<=0.77)
                yy = 0.0285*xx + 0.1733;
            elseif (dg>0.77) && (dg<=0.78)
                yy = 0.0289*xx + 0.139;
            elseif (dg>0.78) && (dg<=0.79)
                yy = 0.0293*xx + 0.1046;
            elseif (dg>0.79) && (dg<=0.8)
                yy = 0.0297*xx + 0.0703;
            elseif (dg>0.8) && (dg<=0.81)
                yy = 0.0301*xx + 0.0434;
            elseif (dg>0.81) && (dg<=0.82)
                yy = 0.0305*xx + 0.0164;
            elseif (dg>0.82) && (dg<=0.83)
                yy = 0.0308*xx - 0.0105;
            elseif (dg>0.83) && (dg<=0.84)
                yy = 0.0312*xx - 0.0375;
            elseif (dg>0.84) && (dg<=0.85)
                yy = 0.0316*xx - 0.0644;
            elseif (dg>0.85) && (dg<=0.86)
                yy = 0.032*xx - 0.0913;
            elseif (dg>0.86) && (dg<=0.87)
                yy = 0.0324*xx - 0.1183;
            elseif (dg>0.87) && (dg<=0.88)
                yy = 0.0327*xx - 0.1452;
            elseif (dg>0.88) && (dg<=0.89)
                yy = 0.0331*xx - 0.1722;
            elseif (dg>0.89) && (dg<=0.9)
                yy = 0.0335*xx - 0.1991;
            elseif (dg>0.9) && (dg<=0.91)
                yy = 0.034*xx - 0.265;
            elseif (dg>0.91) && (dg<=0.92)
                yy = 0.0344*xx - 0.331;
            elseif (dg>0.92) && (dg<=0.93)
                yy = 0.0349*xx - 0.3969;
            elseif (dg>0.93) && (dg<=0.94)
                yy = 0.0353*xx - 0.4628;
            elseif (dg>0.94) && (dg<=0.95)
                yy = 0.0358*xx - 0.5288;
            elseif (dg>0.95) && (dg<=0.96)
                yy = 0.0362*xx - 0.5947;
            elseif (dg>0.96) && (dg<=0.97)
                yy = 0.0367*xx - 0.6606;
            elseif (dg>0.97) && (dg<=0.98)
                yy = 0.0371*xx - 0.7265;
            elseif (dg>0.98) && (dg<=0.99)
                yy = 0.0376*xx - 0.7925;
            elseif (dg>0.99) && (dg<=0.1)
                yy = 0.038*xx - 0.8584;
            end
            
            app.pleidaEditField.Value=num2str(yy);
            
            %%%Lineas -- color rojo
            zx1 = [ yy yy ];
            zy1 = [ 200 xx ];
            plot(app.UIAxes_2,zx1,zy1,'Color', [ 1.00 0.00 0.24],"LineStyle","--")
            
            zx2 = [ 0 yy ];
            zy2 = [ xx xx ];
            plot(app.UIAxes_2,zx2,zy2,'Color', [ 1.00 0.00 0.24],"LineStyle","--")
            
            
            %%%%%
            
            Tcal = (100+(70+1.6*(ProfE/100)))/2;
            app.TCalcEditField.Value=num2str(Tcal);
            
            %%%%%
            
            Tprom = (Pwh + Tfondo)/2;
            app.TpromEditField.Value=num2str(Tprom);
            
            %%%%%
            
            Dpcor = yy*((Tcal+460)/(Tprom+460));
            app.pcorreEditField.Value=num2str(Dpcor);
            
            if dg == 0.6
                yyy = 0.0205*Pdisp + 0.4443;
            elseif (dg>0.6) && (dg<=0.61)
                yyy = 0.0211*Pdisp + 0.3704;
            elseif (dg>0.61) && (dg<=0.62)
                yyy = 0.0216*Pdisp + 0.2965;
            elseif (dg>0.62) && (dg<=0.63)
                yyy = 0.0222*Pdisp + 0.2227;
            elseif (dg>0.63) && (dg<=0.64)
                yyy = 0.0227*Pdisp + 0.1488;
            elseif (dg>0.64) && (dg<=0.65)
                yyy = 0.0233*Pdisp + 0.0749;
            elseif (dg>0.65) && (dg<=0.66)
                yyy = 0.0238*Pdisp + 0.1427;
            elseif (dg>0.66) && (dg<=0.67)
                yyy = 0.0243*Pdisp + 0.2104;
            elseif (dg>0.67) && (dg<=0.68)
                yyy = 0.0247*Pdisp + 0.2782;
            elseif (dg>0.68) && (dg<=0.69)
                yyy = 0.0252*Pdisp + 0.3459;
            elseif (dg>0.69) && (dg<=0.7)
                yyy = 0.0257*Pdisp + 0.4137;
            elseif (dg>0.7) && (dg<=0.71)
                yyy = 0.0261*Pdisp + 0.3794;
            elseif (dg>0.71) && (dg<=0.72)
                yyy = 0.0265*Pdisp+ 0.345;
            elseif (dg>0.72) && (dg<=0.73)
                yyy = 0.0269*Pdisp + 0.3107;
            elseif (dg>0.73) && (dg<=0.74)
                yyy = 0.0273*Pdisp + 0.2763;
            elseif (dg>0.74) && (dg<=0.75)
                yyy = 0.0277*Pdisp + 0.242;
            elseif (dg>0.75) && (dg<=0.76)
                yyy = 0.0281*Pdisp + 0.2077;
            elseif (dg>0.76) && (dg<=0.77)
                yyy = 0.0285*Pdisp + 0.1733;
            elseif (dg>0.77) && (dg<=0.78)
                yyy = 0.0289*Pdisp + 0.139;
            elseif (dg>0.78) && (dg<=0.79)
                yyy = 0.0293*Pdisp + 0.1046;
            elseif (dg>0.79) && (dg<=0.8)
                yyy = 0.0297*Pdisp + 0.0703;
            elseif (dg>0.8) && (dg<=0.81)
                yyy = 0.0301*Pdisp + 0.0434;
            elseif (dg>0.81) && (dg<=0.82)
                yyy = 0.0305*Pdisp + 0.0164;
            elseif (dg>0.82) && (dg<=0.83)
                yyy = 0.0308*Pdisp - 0.0105;
            elseif (dg>0.83) && (dg<=0.84)
                yyy = 0.0312*Pdisp - 0.0375;
            elseif (dg>0.84) && (dg<=0.85)
                yyy = 0.0316*Pdisp - 0.0644;
            elseif (dg>0.85) && (dg<=0.86)
                yyy = 0.032*Pdisp - 0.0913;
            elseif (dg>0.86) && (dg<=0.87)
                yyy = 0.0324*Pdisp - 0.1183;
            elseif (dg>0.87) && (dg<=0.88)
                yyy = 0.0327*Pdisp - 0.1452;
            elseif (dg>0.88) && (dg<=0.89)
                yyy = 0.0331*Pdisp - 0.1722;
            elseif (dg>0.89) && (dg<=0.9)
                yyy = 0.0335*Pdisp - 0.1991;
            elseif (dg>0.9) && (dg<=0.91)
                yyy = 0.034*Pdisp - 0.265;
            elseif (dg>0.91) && (dg<=0.92)
                yyy = 0.0344*Pdisp - 0.331;
            elseif (dg>0.92) && (dg<=0.93)
                yyy = 0.0349*Pdisp - 0.3969;
            elseif (dg>0.93) && (dg<=0.94)
                yyy = 0.0353*Pdisp - 0.4628;
            elseif (dg>0.94) && (dg<=0.95)
                yyy = 0.0358*Pdisp - 0.5288;
            elseif (dg>0.95) && (dg<=0.96)
                yyy = 0.0362*Pdisp- 0.5947;
            elseif (dg>0.96) && (dg<=0.97)
                yyy = 0.0367*Pdisp - 0.6606;
            elseif (dg>0.97) && (dg<=0.98)
                yyy = 0.0371*Pdisp - 0.7265;
            elseif (dg>0.98) && (dg<=0.99)
                yyy = 0.0376*Pdisp - 0.7925;
            elseif (dg>0.99) && (dg<=0.1)
                yyy = 0.038*Pdisp - 0.8584;
            end
            
            app.pleida2EditField.Value=num2str(yyy);
            
            
            %%%Lineas -- color verde
            wx1 = [ yyy yyy ];
            wy1 = [ 200 Pdisp ];
            plot(app.UIAxes_2,wx1,wy1,'Color', [ 0.00,1.00,0.03],"LineStyle","--")
            
            wx2 = [ 0 yyy ];
            wy2 = [ Pdisp Pdisp ];
            plot(app.UIAxes_2,wx2,wy2,'Color', [ 0.00,1.00,0.03],"LineStyle","--")
            
            %%%%%
            
            Dpcor2 = yyy*((Tcal+460)/(Tprom+460));
            app.pcorre2EditField.Value=num2str(Dpcor2);
            
            %%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%Calculo de la presion de operacion y la presion disponible%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%Tabla 1
            Prof1 = ProfE / 2;
            Prof2 = ProfE;
            PO1 = xx + (Dpcor*(Prof1/1000));
            PO2 = xx + (Dpcor*(Prof2/1000));
            PD1 = Pdisp + (Dpcor2*(Prof1/1000));
            PD2 = Pdisp + (Dpcor2*ProfE/1000);
            A = [0 xx Pdisp; Prof1 PO1 PD1; Prof2 PO2 PD2];
            app.UITable.Data = A;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            gradFC=((gfc)*((30.48)^3))*(1/453)*((1/12)^2);
            app.grafFCEditField.Value=num2str(gradFC);
            
            %%%Linea del gradiente del fluido de control
            
            Profundidad1=0;
            Profundidad2=250;
            Profundidad3=500;
            Profundidad4=750;
            Profundidad5=1000;
            Profundidad6=1250;
            Profundidad7=1500;
            Profundidad8=1750;
            Profundidad9=2000;
            
            Presion1=Pwh+(gradFC*Profundidad1);
            Presion2=Pwh+(gradFC*Profundidad2);
            Presion3=Pwh+(gradFC*Profundidad3);
            Presion4=Pwh+(gradFC*Profundidad4);
            Presion5=Pwh+(gradFC*Profundidad5);
            Presion6=Pwh+(gradFC*Profundidad6);
            Presion7=Pwh+(gradFC*Profundidad7);
            Presion8=Pwh+(gradFC*Profundidad8);
            Presion9=Pwh+(gradFC*Profundidad9);
            
            B = [Profundidad1 Presion1; Profundidad2 Presion2; Profundidad3 Presion3;...
                Profundidad4 Presion4;Profundidad5 Presion5;Profundidad6 Presion6;...
                Profundidad7 Presion7;Profundidad8 Presion8;Profundidad9 Presion9;];
            app.UITable3.Data = B;
            
            Profy=[Profundidad1 Profundidad2 Profundidad3 Profundidad4 Profundidad5 Profundidad6...
                Profundidad7 Profundidad8 Profundidad9 ];
            Prex=[Presion1 Presion2 Presion3 Presion4 Presion5 Presion6 Presion7 Presion8 Presion9];
            plot(app.UIAxes2,Prex,Profy,'Color', "magenta","Marker","x")
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Grafico Valvulas%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Prof1a=Prof1/2;
            
            hold(app.UIAxes2,'on');
            
            linea_pdisx=[Pdisp PD2];
            linea_pdisy=[0 ProfE];
            plot(app.UIAxes2,linea_pdisx,linea_pdisy,'Color', "g")
            
            linea_psox=[xx PO2];
            linea_psoy=[ 0 ProfE];
            plot(app.UIAxes2,linea_psox,linea_psoy,'Color', [0.74 0.64 0.58]);


            Nivel_Dinamicox=[0 Pwf];
            Nivel_Dinamicoy=[ND ProfE];
            plot(app.UIAxes2,Nivel_Dinamicox,Nivel_Dinamicoy,'Color',[0.59 0.74 0.60]);
            
            %%%%Punto de balance%%%%%
            [xPunto_Balance,yPunto_Balance] = polyxpoly(linea_psox,linea_psoy,Nivel_Dinamicox,Nivel_Dinamicoy)
            plot(app.UIAxes2,xPunto_Balance,yPunto_Balance,"Marker","o","Color","r")
            
            %%Punto de inyeccion
            %linea_Pwhx,linea_Pwhy
            f=xPunto_Balance-100;
            linea_P_iny_x=[f f];
            linea_P_iny_y=[0 ProfE];
            %plot(app.UIAxes2,linea_Pwhx,linea_Pwhy,'Color', "r")
            
            %%interseccion de -100 al Punto de balance
            [x1Punto_Balance,y1Punto_Balance] = polyxpoly(Nivel_Dinamicox,Nivel_Dinamicoy,linea_P_iny_x,linea_P_iny_y)
            plot(app.UIAxes2,x1Punto_Balance,y1Punto_Balance,"Marker","*","Color","k")
            
            
            %%%Linea de la Pwh al punto de inyeccion
            linea1_Pwhx=[Pwh x1Punto_Balance];
            linea1_Pwhy=[0 y1Punto_Balance];
            plot(app.UIAxes2,linea1_Pwhx,linea1_Pwhy,"Color",[0.64 0.58 0.74],"LineStyle","-.")
            
%             %%%%Presion en la TP de Diseño%%%%
            P_TP_diseno=Pwh+200;
            
            %%Linea de la TP Diseño a -100 del Punto de balance
            lineaTP_disenox=[P_TP_diseno x1Punto_Balance];
            lineaTP_disenoy=[0 y1Punto_Balance];
            plot(app.UIAxes2,lineaTP_disenox,lineaTP_disenoy,"Color",[0.90 0.73 0.38],"LineStyle","-.")
            
            %%%Pendiente
            %prof (0-2000)
            %presion (100-837)
            %max valor de x Pwf 2200
            %m=((2000-0)/(837-100))*(Pwf-100)+0;
            %m1=((Prof1a-0)/(Presion9-100))*(Pwf-Pwh)+0
            m=((Prof1a-0)/(Presion9-Pwh));
            EcRv1=(m)*(Pwf-Presion9)+Prof1a;
            
            %%%%%%%%%%%%%%%%%%%%%%%    Prfundidad y presion Valvula 1      %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%Marcador azul*
            linea_v1x=[Pwh Pwf];
            linea_v1y=[0 EcRv1];    
            [Presion_Valvula1x,Prof_Valvula1y] = polyxpoly(linea_v1x,linea_v1y,linea_pdisx,linea_pdisy);            
            plot(app.UIAxes2,Presion_Valvula1x,Prof_Valvula1y,"Marker","*","Color","b")
            %%%Linea valvula 1 (inclinada)
            linea_v1x1=[Pwh Presion_Valvula1x];
            linea_v1y1=[0 Prof_Valvula1y];
            plot(app.UIAxes2,linea_v1x1,linea_v1y1,"Color","k","LineStyle","-")
%             
            l_v1x=[0 Presion_Valvula1x  ];%%linea roja imaginaria 
            l_v1y=[Prof_Valvula1y Prof_Valvula1y];
            
            %%Marcador naranja Valvula 1
            [Presion_v1x,Profl_v1y] = polyxpoly(l_v1x,l_v1y,lineaTP_disenox,lineaTP_disenoy);
            plot(app.UIAxes2,Presion_v1x,Profl_v1y,"Marker","*","Color","[0.8500 0.3250 0.0980]")
            
            %%Linea valvula 1 (horizontal)
            lx_v1=[Presion_v1x Presion_Valvula1x];
            ly_v1=[Profl_v1y Prof_Valvula1y];
            plot(app.UIAxes2,lx_v1,ly_v1,"Color","k","LineStyle","-")
            
            %%Marcador rojo Valvula 1
            [Presion_v11x,Profl_v11y] = polyxpoly(l_v1x,l_v1y,linea1_Pwhx,linea1_Pwhy);
            plot(app.UIAxes2,Presion_v11x,Profl_v11y,"Marker","*","Color","r")
             
             %%%%%%%%%%%%%%%%%%%%%%%    Prfundidad y presion Valvula 2      %%%%%%%%%%%%%%%%%%%%%%%%%%%%
             %%pendiente
            
            %m=((Prof1a-0)/(Presion9-100))*(Pwf-Presion9)+Prof1a;
            EcRv2=(m)*(Pwf - Presion_v1x) + Profl_v1y;
                         
             %%m2 = ((Prof_Valvula1y-0)/(Presion_Valvula1x-Pwh));
%             mV2 = m2 * (Pwf - Presion_v1x) + Profl_v1y;
%             
%             
            %%Marcador azul*
            linea_v2x=[Presion_v1x Pwf];
            linea_v2y=[Profl_v1y EcRv2];
            [Presion_Valvula2x,Prof_Valvula2y] = polyxpoly(linea_v2x,linea_v2y,linea_psox,linea_psoy);
            plot(app.UIAxes2,Presion_Valvula2x,Prof_Valvula2y,"Marker","*","Color","b")
            
            %%%Linea valvula 2 (inclinada)
            linea_v2x1=[Presion_v1x Presion_Valvula2x];
            linea_v2y1=[Profl_v1y Prof_Valvula2y];
            plot(app.UIAxes2,linea_v2x1,linea_v2y1,"Color","k","LineStyle","-")
            
            l_v2x=[0 Presion_Valvula2x  ];
            l_v2y=[Prof_Valvula2y Prof_Valvula2y];
            
            %%Marcador naranja Valvula 2
            [Presion_v2x,Profl_v2y] = polyxpoly(l_v2x,l_v2y,lineaTP_disenox,lineaTP_disenoy);
            plot(app.UIAxes2,Presion_v2x,Profl_v2y,"Marker","*","Color","[0.8500 0.3250 0.0980]")
            
            %%Linea valvula 2 (horizontal)
            lx_v2=[Presion_v2x Presion_Valvula2x];
            ly_v2=[Profl_v2y Prof_Valvula2y];
            plot(app.UIAxes2,lx_v2,ly_v2,"Color","k","LineStyle","-")
            
            %%Marcador rojo Valvula 2
            [Presion_v22x,Profl_v22y] = polyxpoly(l_v2x,l_v2y,linea1_Pwhx,linea1_Pwhy);
            plot(app.UIAxes2,Presion_v22x,Profl_v22y,"Marker","*","Color","r")
            
%             %%%%%%%%%%%%%%%%%%%%%%%    Prfundidad y presion Valvula 3      %%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             %%pendiente
            EcRv3=(m)*(Pwf - Presion_v2x) + Profl_v2y;
%             mV3 = m2 * (Pwf - Presion_v2x) + Profl_v2y;
%              
%             
            %%Marcador azul*
            linea_v3x=[Presion_v2x Pwf];
            linea_v3y=[Profl_v2y EcRv3];
            [Presion_Valvula3x,Prof_Valvula3y] = polyxpoly(linea_v3x,linea_v3y,linea_psox,linea_psoy);
            plot(app.UIAxes2,Presion_Valvula3x,Prof_Valvula3y,"Marker","*","Color","b")
            
            %%%Linea valvula 3 (inclinada)
            linea_v3x1=[Presion_v2x Presion_Valvula3x];
            linea_v3y1=[Profl_v2y Prof_Valvula3y];
            plot(app.UIAxes2,linea_v3x1,linea_v3y1,"Color","k","LineStyle","-")
            
            l_v3x=[0 Presion_Valvula3x  ];
            l_v3y=[Prof_Valvula3y Prof_Valvula3y];
            
            %%Marcador naranja Valvula 3
            [Presion_v3x,Profl_v3y] = polyxpoly(l_v3x,l_v3y,lineaTP_disenox,lineaTP_disenoy);
            plot(app.UIAxes2,Presion_v3x,Profl_v3y,"Marker","*","Color","[0.8500 0.3250 0.0980]")
            
            %%Linea valvula 3 (horizontal)
            lx_v3=[Presion_v3x Presion_Valvula3x];
            ly_v3=[Profl_v3y Prof_Valvula3y];
            plot(app.UIAxes2,lx_v3,ly_v3,"Color","k","LineStyle","-")
            
            %%Marcador rojo Valvula 3
            [Presion_v33x,Profl_v33y] = polyxpoly(l_v3x,l_v3y,linea1_Pwhx,linea1_Pwhy);
            plot(app.UIAxes2,Presion_v33x,Profl_v33y,"Marker","*","Color","r")
            
%             %%%%%%%%%%%%%%%%%%%%%%%    Prfundidad y presion Valvula 4      %%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             %%pendiente
            EcRv3=(m)*(Pwf - Presion_v3x) + Profl_v3y;
%             
%             mV4 = m2 * (Pwf - Presion_v3x) + Profl_v3y;

%             
%             
            %%Marcador azul*
            linea_v4x=[Presion_v3x Pwf];
            linea_v4y=[Profl_v3y EcRv3];
            [Presion_Valvula4x,Prof_Valvula4y] = polyxpoly(linea_v4x,linea_v4y,linea_psox,linea_psoy);
            plot(app.UIAxes2,Presion_Valvula4x,Prof_Valvula4y,"Marker","*","Color","b")
            
            
            %%%Linea valvula 4 (inclinada)
            linea_v4x1=[Presion_v3x Presion_Valvula4x];
            linea_v4y1=[Profl_v3y Prof_Valvula4y];
            plot(app.UIAxes2,linea_v4x1,linea_v4y1,"Color","k","LineStyle","-")
            
            Prof_Valvula4y1=4606.13;
            l_v4x=[0 Presion_Valvula4x  ];
            l_v4y=[Prof_Valvula4y1 Prof_Valvula4y1];
            
            
            %Marcador naranja Valvula 4
            [Presion_v4x,Profl_v4y] = polyxpoly(l_v4x,l_v4y,lineaTP_disenox,lineaTP_disenoy);
            plot(app.UIAxes2,Presion_v4x,Profl_v4y,"Marker","*","Color","[0.8500 0.3250 0.0980]")


            %%Linea valvula 4 (horizontal)
            lx_v4=[Presion_v4x Presion_Valvula4x];
            ly_v4=[Profl_v4y Prof_Valvula4y1];
            plot(app.UIAxes2,lx_v4,ly_v4,"Color","k","LineStyle","-")
            
            %%Marcador rojo Valvula 4
            [Presion_v44x,Profl_v44y] = polyxpoly(l_v4x,l_v4y,linea1_Pwhx,linea1_Pwhy);
            plot(app.UIAxes2,Presion_v44x,Profl_v44y,"Marker","*","Color","r")
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Grafico de Temperatura%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.UIAxes3,'on');
            tx=[Tcabeza Tfondo];
            ty=[0 ProfE];
            plot(app.UIAxes3,tx,ty,"Color","b","LineStyle","-")


            %Temperatura Valvula 1
            tv1x=[Tcabeza Tfondo ];
            tv1y=[Prof_Valvula1y Prof_Valvula1y];
            [Temp_V1x,Prof_V1y] = polyxpoly(tv1x,tv1y,tx,ty);
            plot(app.UIAxes3,Temp_V1x,Prof_V1y,"Marker","x","Color","k")
            
            tv11x=[Tcabeza Temp_V1x ];
            tv11y=[Prof_Valvula1y Prof_Valvula1y];
            plot(app.UIAxes3,tv11x,tv11y,"Color","k","LineStyle","-.")
            
            tv11xa=[Temp_V1x Temp_V1x ];
            tv11ya=[0 Prof_Valvula1y];
            plot(app.UIAxes3,tv11xa,tv11ya,"Color","k","LineStyle","-.")
            
            
            %Temperatura Valvula 2
            tv2x=[Tcabeza Tfondo ];
            tv2y=[Prof_Valvula2y Prof_Valvula2y];
            [Temp_V2x,Prof_V2y] = polyxpoly(tv2x,tv2y,tx,ty);
            plot(app.UIAxes3,Temp_V2x,Prof_V2y,"Marker","x","Color","k")
            
            tv22x=[Tcabeza Temp_V2x ];
            tv22y=[Prof_Valvula2y Prof_Valvula2y];
            plot(app.UIAxes3,tv22x,tv22y,"Color","k","LineStyle","-.")
            
            tv22xa=[Temp_V2x Temp_V2x ];
            tv22ya=[0 Prof_Valvula2y];
            plot(app.UIAxes3,tv22xa,tv22ya,"Color","k","LineStyle","-.")
            
            %Temperatura Valvula 3
            tv3x=[Tcabeza Tfondo ];
            tv3y=[Prof_Valvula3y Prof_Valvula3y];
            [Temp_V3x,Prof_V3y] = polyxpoly(tv3x,tv3y,tx,ty);
            plot(app.UIAxes3,Temp_V3x,Prof_V3y,"Marker","x","Color","k")
            
            tv33x=[Tcabeza Temp_V3x ];
            tv33y=[Prof_Valvula3y Prof_Valvula3y];
            plot(app.UIAxes3,tv33x,tv33y,"Color","k","LineStyle","-.")
            
            tv33xa=[Temp_V3x Temp_V3x ];
            tv33ya=[0 Prof_Valvula3y];
            plot(app.UIAxes3,tv33xa,tv33ya,"Color","k","LineStyle","-.")
            
            %Temperatura Valvula 4
            tv4x=[Tcabeza Tfondo ];
            tv4y=[Prof_Valvula4y Prof_Valvula4y];
            [Temp_V4x,Prof_V4y] = polyxpoly(tv4x,tv4y,tx,ty);
            plot(app.UIAxes3,Temp_V4x,Prof_V4y,"Marker","x","Color","k")
            
            tv44x=[Tcabeza Temp_V4x ];
            tv44y=[Prof_Valvula4y Prof_Valvula4y];
            plot(app.UIAxes3,tv44x,tv44y,"Color","k","LineStyle","-.")
            
            tv44xa=[Temp_V4x Temp_V4x ];
            tv44ya=[0 Prof_Valvula4y];
            plot(app.UIAxes3,tv44xa,tv44ya,"Color","k","LineStyle","-.")
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Tabla de resulados%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
%             
%             
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%TABLA 1%%%%%%%%%%%%%%%%%%%%%%%
            %%Numero de Valvulas
            Valvulas = [1 2 3 4];
            NoValvulas=Valvulas';            
            
            %%Profundidad de las valvulas
            Prof_Valvulas=[Prof_Valvula1y Prof_Valvula2y Prof_Valvula3y Prof_Valvula4y1];
            Porf_Valvulas_v=Prof_Valvulas';
            
            %%Temperatura de las Valvulas
            Temp_Valvulas=[ Temp_V1x Temp_V2x Temp_V3x Temp_V4x];
            Temp_Valvulas_v=Temp_Valvulas';
            
            Pt_diseno=[Presion_v1x Presion_v2x Presion_v3x Presion_v4x];
            Pt_diseno_v=Pt_diseno';
            
            Pt_fluyendo=[Presion_v11x Presion_v22x Presion_v33x Presion_v44x];
            Pt_fluyendo_v=Pt_fluyendo';
            
            Psodv1=xx;
            Psodv2=xx-10;
            Psodv3=Psodv2-10;
            Psodv4=Psodv3-10;
            
            Psd=[Psodv1 Psodv2 Psodv3 Psodv4];
            Psdv=Psd';          
                        
            C=[NoValvulas Porf_Valvulas_v Temp_Valvulas_v Pt_diseno_v Pt_fluyendo_v Psdv ];            
            app.UITable2.Data = C;
%             
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%TABLA 2%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%DPleido valvula 2
            
            xx2=Psodv2;
            xx3=Psodv3;
            xx4=Psodv4;
            
            if dg == 0.6
                dplv2 = 0.0205*xx2 + 0.4443;
            elseif (dg>0.6) && (dg<=0.61)
                dplv2 = 0.0211*xx2 + 0.3704;
            elseif (dg>0.61) && (dg<=0.62)
                dplv2 = 0.0216*xx2 + 0.2965;
            elseif (dg>0.62) && (dg<=0.63)
                dplv2 = 0.0222*xx2 + 0.2227;
            elseif (dg>0.63) && (dg<=0.64)
                dplv2 = 0.0227*xx2 + 0.1488;
            elseif (dg>0.64) && (dg<=0.65)
                dplv2 = 0.0233*xx2 + 0.0749;
            elseif (dg>0.65) && (dg<=0.66)
                dplv2 = 0.0238*xx2 + 0.1427;
            elseif (dg>0.66) && (dg<=0.67)
                dplv2 = 0.0243*xx2 + 0.2104;
            elseif (dg>0.67) && (dg<=0.68)
                dplv2 = 0.0247*xx2 + 0.2782;
            elseif (dg>0.68) && (dg<=0.69)
                dplv2 = 0.0252*xx2 + 0.3459;
            elseif (dg>0.69) && (dg<=0.7)
                dplv2 = 0.0257*xx2 + 0.4137;
            elseif (dg>0.7) && (dg<=0.71)
                dplv2 = 0.0261*xx2 + 0.3794;
            elseif (dg>0.71) && (dg<=0.72)
                dplv2 = 0.0265*xx2 + 0.345;
            elseif (dg>0.72) && (dg<=0.73)
                dplv2 = 0.0269*xx2 + 0.3107;
            elseif (dg>0.73) && (dg<=0.74)
                dplv2 = 0.0273*xx2 + 0.2763;
            elseif (dg>0.74) && (dg<=0.75)
                dplv2 = 0.0277*xx2 + 0.242;
            elseif (dg>0.75) && (dg<=0.76)
                dplv2 = 0.0281*xx2 + 0.2077;
            elseif (dg>0.76) && (dg<=0.77)
                dplv2 = 0.0285*xx2 + 0.1733;
            elseif (dg>0.77) && (dg<=0.78)
                dplv2 = 0.0289*xx2 + 0.139;
            elseif (dg>0.78) && (dg<=0.79)
                dplv2 = 0.0293*xx2 + 0.1046;
            elseif (dg>0.79) && (dg<=0.8)
                dplv2 = 0.0297*xx2 + 0.0703;
            elseif (dg>0.8) && (dg<=0.81)
                dplv2 = 0.0301*xx2 + 0.0434;
            elseif (dg>0.81) && (dg<=0.82)
                dplv2 = 0.0305*xx2 + 0.0164;
            elseif (dg>0.82) && (dg<=0.83)
                dplv2 = 0.0308*xx2 - 0.0105;
            elseif (dg>0.83) && (dg<=0.84)
                dplv2 = 0.0312*xx2 - 0.0375;
            elseif (dg>0.84) && (dg<=0.85)
                dplv2 = 0.0316*xx2 - 0.0644;
            elseif (dg>0.85) && (dg<=0.86)
                dplv2 = 0.032*xx2 - 0.0913;
            elseif (dg>0.86) && (dg<=0.87)
                dplv2 = 0.0324*xx2 - 0.1183;
            elseif (dg>0.87) && (dg<=0.88)
                dplv2 = 0.0327*xx2 - 0.1452;
            elseif (dg>0.88) && (dg<=0.89)
                dplv2 = 0.0331*xx2 - 0.1722;
            elseif (dg>0.89) && (dg<=0.9)
                dplv2 = 0.0335*xx2 - 0.1991;
            elseif (dg>0.9) && (dg<=0.91)
                dplv2 = 0.034*xx2 - 0.265;
            elseif (dg>0.91) && (dg<=0.92)
                dplv2 = 0.0344*xx2 - 0.331;
            elseif (dg>0.92) && (dg<=0.93)
                dplv2 = 0.0349*xx2 - 0.3969;
            elseif (dg>0.93) && (dg<=0.94)
                dplv2 = 0.0353*xx2 - 0.4628;
            elseif (dg>0.94) && (dg<=0.95)
                dplv2 = 0.0358*xx2 - 0.5288;
            elseif (dg>0.95) && (dg<=0.96)
                dplv2 = 0.0362*xx2 - 0.5947;
            elseif (dg>0.96) && (dg<=0.97)
                dplv2 = 0.0367*xx2 - 0.6606;
            elseif (dg>0.97) && (dg<=0.98)
                dplv2 = 0.0371*xx2 - 0.7265;
            elseif (dg>0.98) && (dg<=0.99)
                dplv2 = 0.0376*xx2 - 0.7925;
            elseif (dg>0.99) && (dg<=0.1)
                dplv2 = 0.038*xx2 - 0.8584;
            end
            if dg == 0.6
                dplv3 = 0.0205*xx3 + 0.4443;
            elseif (dg>0.6) && (dg<=0.61)
                dplv3 = 0.0211*xx3 + 0.3704;
            elseif (dg>0.61) && (dg<=0.62)
                dplv3 = 0.0216*xx3 + 0.2965;
            elseif (dg>0.62) && (dg<=0.63)
                dplv3 = 0.0222*xx3 + 0.2227;
            elseif (dg>0.63) && (dg<=0.64)
                dplv3 = 0.0227*xx3 + 0.1488;
            elseif (dg>0.64) && (dg<=0.65)
                dplv3 = 0.0233*xx3 + 0.0749;
            elseif (dg>0.65) && (dg<=0.66)
                dplv3 = 0.0238*xx3 + 0.1427;
            elseif (dg>0.66) && (dg<=0.67)
                dplv3 = 0.0243*xx3 + 0.2104;
            elseif (dg>0.67) && (dg<=0.68)
                dplv3 = 0.0247*xx3 + 0.2782;
            elseif (dg>0.68) && (dg<=0.69)
                dplv3 = 0.0252*xx3 + 0.3459;
            elseif (dg>0.69) && (dg<=0.7)
                dplv3 = 0.0257*xx3 + 0.4137;
            elseif (dg>0.7) && (dg<=0.71)
                dplv3 = 0.0261*xx3 + 0.3794;
            elseif (dg>0.71) && (dg<=0.72)
                dplv3 = 0.0265*xx3 + 0.345;
            elseif (dg>0.72) && (dg<=0.73)
                dplv3 = 0.0269*xx3 + 0.3107;
            elseif (dg>0.73) && (dg<=0.74)
                dplv3 = 0.0273*xx3 + 0.2763;
            elseif (dg>0.74) && (dg<=0.75)
                dplv3 = 0.0277*xx3 + 0.242;
            elseif (dg>0.75) && (dg<=0.76)
                dplv3 = 0.0281*xx3 + 0.2077;
            elseif (dg>0.76) && (dg<=0.77)
                dplv3 = 0.0285*xx3 + 0.1733;
            elseif (dg>0.77) && (dg<=0.78)
                dplv3 = 0.0289*xx3 + 0.139;
            elseif (dg>0.78) && (dg<=0.79)
                dplv3 = 0.0293*xx3 + 0.1046;
            elseif (dg>0.79) && (dg<=0.8)
                dplv3 = 0.0297*xx3 + 0.0703;
            elseif (dg>0.8) && (dg<=0.81)
                dplv3 = 0.0301*xx3 + 0.0434;
            elseif (dg>0.81) && (dg<=0.82)
                dplv3 = 0.0305*xx3 + 0.0164;
            elseif (dg>0.82) && (dg<=0.83)
                dplv3 = 0.0308*xx3 - 0.0105;
            elseif (dg>0.83) && (dg<=0.84)
                dplv3 = 0.0312*xx3 - 0.0375;
            elseif (dg>0.84) && (dg<=0.85)
                dplv3 = 0.0316*xx3 - 0.0644;
            elseif (dg>0.85) && (dg<=0.86)
                dplv3 = 0.032*xx3 - 0.0913;
            elseif (dg>0.86) && (dg<=0.87)
                dplv3 = 0.0324*xx3 - 0.1183;
            elseif (dg>0.87) && (dg<=0.88)
                dplv3 = 0.0327*xx3 - 0.1452;
            elseif (dg>0.88) && (dg<=0.89)
                dplv3 = 0.0331*xx3 - 0.1722;
            elseif (dg>0.89) && (dg<=0.9)
                dplv3 = 0.0335*xx3 - 0.1991;
            elseif (dg>0.9) && (dg<=0.91)
                dplv3 = 0.034*xx3 - 0.265;
            elseif (dg>0.91) && (dg<=0.92)
                dplv3 = 0.0344*xx3 - 0.331;
            elseif (dg>0.92) && (dg<=0.93)
                dplv3 = 0.0349*xx3 - 0.3969;
            elseif (dg>0.93) && (dg<=0.94)
                dplv3 = 0.0353*xx3 - 0.4628;
            elseif (dg>0.94) && (dg<=0.95)
                dplv3 = 0.0358*xx3 - 0.5288;
            elseif (dg>0.95) && (dg<=0.96)
                dplv3 = 0.0362*xx3 - 0.5947;
            elseif (dg>0.96) && (dg<=0.97)
                dplv3 = 0.0367*xx3 - 0.6606;
            elseif (dg>0.97) && (dg<=0.98)
                dplv3 = 0.0371*xx3 - 0.7265;
            elseif (dg>0.98) && (dg<=0.99)
                dplv3 = 0.0376*xx3 - 0.7925;
            elseif (dg>0.99) && (dg<=0.1)
                dplv3 = 0.038*xx3 - 0.8584;
            end
            if dg == 0.6
                dplv4 = 0.0205*xx4 + 0.4443;
            elseif (dg>0.6) && (dg<=0.61)
                dplv4 = 0.0211*xx4 + 0.3704;
            elseif (dg>0.61) && (dg<=0.62)
                dplv4 = 0.0216*xx4 + 0.2965;
            elseif (dg>0.62) && (dg<=0.63)
                dplv4 = 0.0222*xx4 + 0.2227;
            elseif (dg>0.63) && (dg<=0.64)
                dplv4 = 0.0227*xx4 + 0.1488;
            elseif (dg>0.64) && (dg<=0.65)
                dplv4 = 0.0233*xx4 + 0.0749;
            elseif (dg>0.65) && (dg<=0.66)
                dplv4 = 0.0238*xx4 + 0.1427;
            elseif (dg>0.66) && (dg<=0.67)
                dplv4 = 0.0243*xx4 + 0.2104;
            elseif (dg>0.67) && (dg<=0.68)
                dplv4 = 0.0247*xx4 + 0.2782;
            elseif (dg>0.68) && (dg<=0.69)
                dplv4 = 0.0252*xx4 + 0.3459;
            elseif (dg>0.69) && (dg<=0.7)
                dplv4 = 0.0257*xx4 + 0.4137;
            elseif (dg>0.7) && (dg<=0.71)
                dplv4 = 0.0261*xx4 + 0.3794;
            elseif (dg>0.71) && (dg<=0.72)
                dplv4 = 0.0265*xx4 + 0.345;
            elseif (dg>0.72) && (dg<=0.73)
                dplv4 = 0.0269*xx4 + 0.3107;
            elseif (dg>0.73) && (dg<=0.74)
                dplv4 = 0.0273*xx4 + 0.2763;
            elseif (dg>0.74) && (dg<=0.75)
                dplv4 = 0.0277*xx4 + 0.242;
            elseif (dg>0.75) && (dg<=0.76)
                dplv4 = 0.0281*xx4 + 0.2077;
            elseif (dg>0.76) && (dg<=0.77)
                dplv4 = 0.0285*xx4 + 0.1733;
            elseif (dg>0.77) && (dg<=0.78)
                dplv4 = 0.0289*xx4 + 0.139;
            elseif (dg>0.78) && (dg<=0.79)
                dplv4 = 0.0293*xx4 + 0.1046;
            elseif (dg>0.79) && (dg<=0.8)
                dplv4 = 0.0297*xx4 + 0.0703;
            elseif (dg>0.8) && (dg<=0.81)
                dplv4 = 0.0301*xx4 + 0.0434;
            elseif (dg>0.81) && (dg<=0.82)
                dplv4 = 0.0305*xx4 + 0.0164;
            elseif (dg>0.82) && (dg<=0.83)
                dplv4 = 0.0308*xx4 - 0.0105;
            elseif (dg>0.83) && (dg<=0.84)
                dplv4 = 0.0312*xx4 - 0.0375;
            elseif (dg>0.84) && (dg<=0.85)
                dplv4 = 0.0316*xx4 - 0.0644;
            elseif (dg>0.85) && (dg<=0.86)
                dplv4 = 0.032*xx4 - 0.0913;
            elseif (dg>0.86) && (dg<=0.87)
                dplv4 = 0.0324*xx4 - 0.1183;
            elseif (dg>0.87) && (dg<=0.88)
                dplv4 = 0.0327*xx4 - 0.1452;
            elseif (dg>0.88) && (dg<=0.89)
                dplv4 = 0.0331*xx4 - 0.1722;
            elseif (dg>0.89) && (dg<=0.9)
                dplv4 = 0.0335*xx4 - 0.1991;
            elseif (dg>0.9) && (dg<=0.91)
                dplv4 = 0.034*xx4- 0.265;
            elseif (dg>0.91) && (dg<=0.92)
                dplv4 = 0.0344*xx4 - 0.331;
            elseif (dg>0.92) && (dg<=0.93)
                dplv4 = 0.0349*xx4 - 0.3969;
            elseif (dg>0.93) && (dg<=0.94)
                dplv4 = 0.0353*xx4 - 0.4628;
            elseif (dg>0.94) && (dg<=0.95)
                dplv4 = 0.0358*xx4 - 0.5288;
            elseif (dg>0.95) && (dg<=0.96)
                dplv4 = 0.0362*xx4 - 0.5947;
            elseif (dg>0.96) && (dg<=0.97)
                dplv4 = 0.0367*xx4 - 0.6606;
            elseif (dg>0.97) && (dg<=0.98)
                dplv4 = 0.0371*xx4 - 0.7265;
            elseif (dg>0.98) && (dg<=0.99)
                dplv4 = 0.0376*xx4 - 0.7925;
            elseif (dg>0.99) && (dg<=0.1)
                dplv4 = 0.038*xx4 - 0.8584;
            end            
            
            
            Dpleido=[yy dplv2 dplv3 dplv4 ];
            Dpleidov=Dpleido';
            
             
            Tcal1=(100+(70+1.6*(Prof_Valvula1y/100)))/(2);
            Tcal2=(100+(70+1.6*(Prof_Valvula2y/100)))/(2);
            Tcal3=(100+(70+1.6*(Prof_Valvula3y/100)))/(2);
            Tcal4=(100+(70+1.6*(Prof_Valvula4y1/100)))/(2);
            
            Tcal=[ Tcal1 Tcal2 Tcal3 Tcal4];
            Tcalv=Tcal';
            
            Treal1=(100+Temp_V1x)/2;
            Treal2=(100+Temp_V2x)/2;
            Treal3=(100+Temp_V3x)/2;
            Treal4=(100+Temp_V4x)/2;
            
            Treal=[ Treal1 Treal2 Treal3 Treal4];
            Trealv=Treal';
            
            dpcv1=(yy/1000)*((Tcal1+460)/(Treal1+460))*(Prof_Valvula1y);
            dpcv2=(dplv2/1000)*((Tcal2+460)/(Treal2+460))*(Prof_Valvula2y);
            dpcv3=(dplv3/1000)*((Tcal3+460)/(Treal3+460))*(Prof_Valvula3y);
            dpcv4=(dplv4/1000)*((Tcal4+460)/(Treal4+460))*(Prof_Valvula4y1);
            
            dpc=[dpcv1 dpcv2 dpcv3 dpcv4];
            dpcv=dpc';


            Pvo1= Psodv1 + dpcv1;
            Pvo2= Psodv2 + dpcv2;
            Pvo3= Psodv3 + dpcv3;
            Pvo4= Psodv4 + dpcv4;
            Pvo=[Pvo1 Pvo2 Pvo3 Pvo4 ];
            Pvov=Pvo';
            
            D = [ Dpleidov Tcalv Trealv dpcv Pvov];
            app.UITable2_3.Data = D;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Calculo del Gasto del Gas%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%Gasto de inyección%%%%%
            
             qgi=(app.RGATEditField.Value-app.RGANEditField.Value)*app.qoEditField.Value;
             app.qgiEditField.Value=num2str(qgi);             
             
             %%%Facotr de corrección
             
             FC=(0.0544)*(dg*(Temp_V4x+460))^0.5;
             app.FCEditField_2.Value=num2str(FC);
             
             %%%%%%%Gasto de inyección corregido
             
             qgic=qgi*FC;
             app.qgicEditField.Value=num2str(qgic);
             
%              
             %%%%%Tercera grafica 
            
            app.TpiEditField.Value=num2str(Temp_V4x);
            if dg == 0.52
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
                
            elseif (dg>0.52) && (dg<=0.53)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.53) && (dg<=0.54)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.54) && (dg<=0.55)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.55) && (dg<=0.56)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.56) && (dg<=0.57)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.57) && (dg<=0.58)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.58) && (dg<=0.59)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.59) && (dg<=0.6)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.6) && (dg<=0.61)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.61) && (dg<=0.62)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.62) && (dg<=0.63)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.63) && (dg<=0.64)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.64) && (dg<=0.65)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.65) && (dg<=0.66)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
                

            elseif (dg>0.66) && (dg<=0.67)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.67) && (dg<=0.68)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.68) && (dg<=0.69)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.69) && (dg<=0.7)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.7) && (dg<=0.71)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.71) && (dg<=0.72)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.72) && (dg<=0.73)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.73) && (dg<=0.74)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.74) && (dg<=0.75)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.75) && (dg<=0.76)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.76) && (dg<=0.77)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.77) && (dg<=0.78)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.78) && (dg<=0.79)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.79) && (dg<=0.8)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.8) && (dg<=0.81)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.81) && (dg<=0.82)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.82) && (dg<=0.83)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.83) && (dg<=0.84)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.84) && (dg<=0.85)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.85) && (dg<=0.86)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.86) && (dg<=0.87)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.87) && (dg<=0.88)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.88) && (dg<=0.89)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            elseif (dg>0.89) && (dg<=0.9)
                if Temp_V4x ==250
                    ym = 0.446*(dg)^2 - 0.9757*(dg) + 1.7039;
                elseif  (Temp_V4x<250) && (Temp_V4x>=240)
                    ym = 0.4399*(dg)^2 - 0.9679*(dg) + 1.7052;
                elseif  (Temp_V4x<240) && (Temp_V4x>=230)
                    ym = 0.4338*(dg)^2 - 0.9602*(dg) + 1.7065;
                elseif  (Temp_V4x<230) && (Temp_V4x>=220)
                    ym = 0.4277*(dg)^2 - 0.9524*(dg) + 1.7078;
                elseif  (Temp_V4x<220) && (Temp_V4x>=210)
                    ym = 0.4216*(dg)^2 - 0.9447*(dg) + 1.7091;
                elseif  (Temp_V4x<210) && (Temp_V4x>=200)
                    ym = 0.4155*(dg)^2 - 0.9369*(dg) + 1.7105;
                elseif  (Temp_V4x<200) && (Temp_V4x>=190)
                    ym = 0.405*(dg)^2- 0.9256*(dg) + 1.7117;
                elseif  (Temp_V4x<190) && (Temp_V4x>=180)
                    ym = 0.3945*(dg)^2- 0.9143*(dg)+ 1.7129;
                elseif  (Temp_V4x<180) && (Temp_V4x>=170)
                    ym = 0.384*(dg)^2 - 0.903*(dg) + 1.7142;
                elseif  (Temp_V4x<170) && (Temp_V4x>=160)
                    ym = 0.3735*(dg)^2 - 0.8917*(dg) + 1.7154;
                elseif  (Temp_V4x<160) && (Temp_V4x>=150)
                    ym = 0.363*(dg)^2 - 0.8805*(dg) + 1.7167;
                elseif  (Temp_V4x<150) && (Temp_V4x>=140)
                    ym = 0.3557*(dg)^2 - 0.8649*(dg) + 1.7118;
                elseif  (Temp_V4x<140) && (Temp_V4x>=130)
                    ym = 0.3484*(dg)^2 - 0.8494*(dg) + 1.7068;
                elseif  (Temp_V4x<130) && (Temp_V4x>=120)
                    ym = 0.3411*(dg)^2 - 0.8339*(dg) + 1.7019;
                elseif  (Temp_V4x<120) && (Temp_V4x>=110)
                    ym = 0.3338*(dg)^2 - 0.8183*(dg) + 1.697;
                elseif  (Temp_V4x<110) && (Temp_V4x>=100)
                    ym = 0.3265*(dg)^2 - 0.8028*(dg) + 1.692;
                elseif  (Temp_V4x<100) && (Temp_V4x>=90)
                    ym = 0.3354*(dg)^2 - 0.809*(dg) + 1.6939;
                elseif  (Temp_V4x<90) && (Temp_V4x>=80)
                    ym = 0.3443*(dg)^2 - 0.8152*(dg) + 1.6957;
                elseif  (Temp_V4x<80) && (Temp_V4x>=70)
                    ym = 0.3532*(dg)^2 - 0.8215*(dg) + 1.6976; 
                elseif  (Temp_V4x<70) && (Temp_V4x>=60)
                    ym = 0.3621*(dg)^2 - 0.8277*(dg) + 1.6994;
                elseif  (Temp_V4x<60) && (Temp_V4x>=50)
                    ym = 0.3709*(dg)^2 - 0.8339*(dg) + 1.7013;        
                end
            end
            
            k=ym-0.02;
            app.kEditField.Value=num2str(k);


            hold(app.UIAxes4,'on')
            
            T250y=[0.9 0.755527 0.663622 0.613043 0.560109 0.52];
            T250x=[1.18629 1.22234 1.25194 1.27099 1.29573 1.31944 ];
            plot(app.UIAxes4,T250x,T250y,"LineStyle","-","Color","[0.30 0.75 0.93]")
            
            T200y=[0.9 0.798933 0.69213 0.635815 0.587511 0.52 ];
            T200x=[1.20286  1.2301  1.26129 1.28144 1.30165 1.33773   ];
            plot(app.UIAxes4,T200x,T200y,"LineStyle","-","Color","[0.85 0.33 0.10]")
            
            T150x=[1.21714 1.25946 1.28962 1.31036 1.33909 1.36115 ];
            T150y=[0.9 0.751669 0.659757 0.605725 0.551601 0.52   ];
            plot(app.UIAxes4,T150x,T150y,"LineStyle","-","Color","[0.64 0.08 0.18]")
            
            T100x=[1.23314 1.26267 1.29663 1.31787 1.33583 1.36458 ];
            T100y=[0.9 0.799704 0.680279 0.617085 0.573385 0.52 ];
            plot(app.UIAxes4,T100x,T100y,"LineStyle","-","Color","[0.00 0.00 1.00]")
            
            T50x=[1.25028 1.28464 1.3063 1.3253 1.3427 1.36915 ];
            T50y=[0.9 0.757104 0.674446 0.617 0.575596 0.52 ];
            plot(app.UIAxes4,T50x,T50y,"LineStyle","-","Color","[0.85 0.33 0.10]")
            
            kx1=[1.1 k];
            ky1=[dg dg];
            plot(app.UIAxes4,kx1,ky1,"LineStyle","-.","Color","red")
            
            kx2=[k k];
            ky2=[0.52 dg];
            plot(app.UIAxes4,kx2,ky2,"LineStyle","-.","Color","red")
            
            CdA=qgic/(155500*Pvo4*((((64.34*(k))/(dg*(Temp_V4x+460)*(k-1)))*...
                (((((Presion_v44x/Pvo4)))^(2/k))-((Presion_v44x/Pvo4)^((k+1)/k)))))^0.5);
            app.CdAEditField.Value=num2str(CdA);
            
            diametro=(64)*((4*CdA)/3.1416)^0.5;
            app.DiametroEditField.Value=num2str(diametro);
            
            R=app.AvEditField.Value/app.AbEditField.Value;
            app.REditField.Value=num2str(R);
            
            %%%%%%%%%%%%%%%%%%%%%Tabla de Resultados%%%%%%%%%%%%%%
            
            PdaTV1=Pvo1*(1-R)+(Presion_v1x*R);
            PdaTV2=Pvo2*(1-R)+(Presion_v2x*R);
            PdaTV3=Pvo3*(1-R)+(Presion_v3x*R);
            PdaTV4=Pvo4*(1-R)+(Presion_v4x*R);
            
            PdaTV=[PdaTV1 PdaTV2 PdaTV3 PdaTV4];
            PdaTVv=PdaTV';
            
            Psc1=PdaTV1-dpcv1;
            Psc2=PdaTV2-dpcv2;
            Psc3=PdaTV3-dpcv3;
            Psc4=PdaTV4-dpcv4;
            
            Psc=[Psc1 Psc2 Psc3 Psc4];
            Pscv=Psc';
            
            %%  ,Ct,Pd@60°F[psi],Ptro [psi],Pvo Real[psi],Pso Real[psi]
            
            ct1=0.85;
            ct2=0.826;
            ct3=0.814;
            ct4=0.803;
            
            ct=[ct1 ct2 ct3 ct4];
            ct_v=ct';
            
            Pda60F1= PdaTV1*ct1;
            Pda60F2= PdaTV2*ct2;
            Pda60F3= PdaTV3*ct3;
            Pda60F4= PdaTV4*ct4;
            
            Pda60F=[Pda60F1 Pda60F2 Pda60F3 Pda60F4 ];
            Pda60F_v=Pda60F';
            
            Ptro1=Pda60F1/(1-R);
            Ptro2=Pda60F2/(1-R);
            Ptro3=Pda60F3/(1-R);
            Ptro4=Pda60F4/(1-R);
            
            Ptro=[Ptro1 Ptro2 Ptro3 Ptro4];
            Ptro_v=Ptro';
            
            Pvor1=((PdaTV1-R*Presion_v11x)/(1-R));
            Pvor2=((PdaTV1-R*Presion_v22x)/(1-R));
            Pvor3=((PdaTV1-R*Presion_v33x)/(1-R));
            Pvor4=((PdaTV1-R*Presion_v44x)/(1-R));
            
            Pvor=[Pvor1 Pvor2 Pvor3 Pvor4];
            Pvor_v=Pvor';
            
            Psor1=Pvor1-dpcv1;
            Psor2=Pvor2-dpcv2;
            Psor3=Pvor3-dpcv3;
            Psor4=Pvor4-dpcv4;
            
            Psor=[Psor1 Psor2 Psor3 Psor4];
            Psor_v=Psor';

            
            Z=[PdaTVv Pscv ct_v Pda60F_v Ptro_v Pvor_v Psor_v];             
            app.UITable2_4.Data = Z;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BombeoNeumticoPanel = uipanel(app.UIFigure);
            app.BombeoNeumticoPanel.Title = 'Bombeo Neumático';
            app.DatosDelProblemaPanel = uipanel(app.BombeoNeumticoPanel);
            app.DatosDelProblemaPanel.Title = 'Datos Del Problema';
            app.qoEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.qoEditFieldLabel.Text = 'qo';
            app.qoEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.qoEditField.Limits = [0 10000000];
            app.qoEditField.ValueDisplayFormat = '%8.f';
            app.qoEditField.Value = 2000;
            app.PwhEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.PwhEditFieldLabel.Text = 'Pwh';
            app.PwhEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.PwhEditField.Value = 100;
            app.ginyEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.ginyEditFieldLabel.Text = 'γg iny';
            app.ginyEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.ginyEditField.Value = 0.65;
            app.TREditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.TREditFieldLabel.Text = 'ɸT.R.';
            app.TREditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.TREditField.Value = 6.625;
            app.TPEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.TPEditFieldLabel.Text = 'ɸT.P.';
            app.TPEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.TPEditField.Value = 2.875;
            app.IPLabel = uilabel(app.DatosDelProblemaPanel);
            app.IPLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.IPEditField.Value = 2.5;
            app.bpdLabel = uilabel(app.DatosDelProblemaPanel);
            app.bpdLabel.Text = 'bpd';
            app.psiLabel = uilabel(app.DatosDelProblemaPanel);
            app.psiLabel.Text = 'psi';
            app.psiLabel_2 = uilabel(app.DatosDelProblemaPanel);
            app.psiLabel_2.Text = 'psi';
            app.psiLabel_3 = uilabel(app.DatosDelProblemaPanel);
            app.psiLabel_3.Text = 'psi';
            app.adimLabel = uilabel(app.DatosDelProblemaPanel);
            app.adimLabel.Text = 'adim';
            app.FCLabel_2 = uilabel(app.DatosDelProblemaPanel);
            app.FCLabel_2.Text = 'ρ FC';
            app.FCEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.FCEditField.Value = 0.85;
            app.Label = uilabel(app.DatosDelProblemaPanel);
            app.pulgLabel = uilabel(app.DatosDelProblemaPanel);
            app.pulgLabel.Text = 'pulg';
            app.pulgLabel_2 = uilabel(app.DatosDelProblemaPanel);
            app.pulgLabel_2.Text = 'pulg';
            app.bpdpsiLabel = uilabel(app.DatosDelProblemaPanel);
            app.bpdpsiLabel.Text = 'bpd/psi';
            app.IniciarButton = uibutton(app.DatosDelProblemaPanel, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar ';
            app.piesLabel = uilabel(app.DatosDelProblemaPanel);
            app.piesLabel.Text = 'pies';
            app.ProfEmpEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.ProfEmpEditFieldLabel.Text = 'Prof Emp';
            app.ProfEmpEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.ProfEmpEditField.Value = 8000;
            app.FLabel = uilabel(app.DatosDelProblemaPanel);
            app.FLabel.Text = '°F';
            app.TwhEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.TwhEditFieldLabel.Text = 'Twh';
            app.TwhEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.TwhEditField.Value = 100;
            app.FLabel_2 = uilabel(app.DatosDelProblemaPanel);
            app.FLabel_2.Text = '°F';
            app.TfondoEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.TfondoEditFieldLabel.Text = 'Tfondo';
            app.TfondoEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.TfondoEditField.Value = 228;
            app.PvalopEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.PvalopEditFieldLabel.Text = 'ΔP val op';
            app.PvalopEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.PvalopEditField.Value = 100;
            app.psiLabel_4 = uilabel(app.DatosDelProblemaPanel);
            app.psiLabel_4.Text = 'psi';
            app.FLabel_3 = uilabel(app.DatosDelProblemaPanel);
            app.FLabel_3.Text = '°F';
            app.TCTallerEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.TCTallerEditFieldLabel.Text = 'T C.Taller';
            app.TCTallerEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.TCTallerEditField.Value = 60;
            app.pie3blLabel = uilabel(app.DatosDelProblemaPanel);
            app.pie3blLabel.Text = 'pie3/bl';
            app.RGANEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.RGANEditFieldLabel.Text = 'RGAN';
            app.RGANEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.RGANEditField.Value = 200;
            app.pie3blLabel_2 = uilabel(app.DatosDelProblemaPanel);
            app.pie3blLabel_2.Text = 'pie3/bl';
            app.RGATEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.RGATEditFieldLabel.Text = 'RGAT';
            app.RGATEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.RGATEditField.Value = 350;
            app.APILabel = uilabel(app.DatosDelProblemaPanel);
            app.APILabel.Text = '°API';
            app.oilEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.oilEditFieldLabel.Text = 'ρ oil';
            app.oilEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.oilEditField.Value = 35;
            app.psiLabel_5 = uilabel(app.DatosDelProblemaPanel);
            app.psiLabel_5.Text = 'psi';
            app.PwsEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.PwsEditFieldLabel.Text = 'Pws';
            app.PwsEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.PwsEditField.Value = 3000;
            app.AbEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.AbEditFieldLabel.Text = 'Ab';
            app.AbEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.AbEditField.Value = 0.3109;
            app.adimLabel_4 = uilabel(app.DatosDelProblemaPanel);
            app.adimLabel_4.Text = 'adim';
            app.PsoEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.PsoEditFieldLabel.Text = 'Pso';
            app.PsoEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.PsoEditField.Value = 950;
            app.PdiEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.PdiEditFieldLabel.Text = 'P di';
            app.PdiEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.PdiEditField.Value = 1000;
            app.adimLabel_5 = uilabel(app.DatosDelProblemaPanel);
            app.adimLabel_5.Text = 'adim';
            app.AvEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.AvEditFieldLabel.Text = 'Av';
            app.AvEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.AvEditField.Value = 0.0291;
            app.adimLabel_6 = uilabel(app.DatosDelProblemaPanel);
            app.adimLabel_6.Text = 'adim';
            app.AguaEditFieldLabel = uilabel(app.DatosDelProblemaPanel);
            app.AguaEditFieldLabel.Text = '% Agua';
            app.AguaEditField = uieditfield(app.DatosDelProblemaPanel, 'numeric');
            app.Panel2 = uipanel(app.BombeoNeumticoPanel);
            app.Panel2.Title = 'Panel2';
            app.psiLabel_6 = uilabel(app.Panel2);
            app.psiLabel_6.Text = 'psi';
            app.PwfEditFieldLabel = uilabel(app.Panel2);
            app.PwfEditFieldLabel.Text = 'Pwf';
            app.PwfEditField = uieditfield(app.Panel2, 'text');
            app.APILabel_2 = uilabel(app.Panel2);
            app.APILabel_2.Text = '10° API';
            app.APILabel_3 = uilabel(app.Panel2);
            app.APILabel_3.Text = '15° API';
            app.APILabel_4 = uilabel(app.Panel2);
            app.APILabel_4.Text = '20° API';
            app.APILabel_5 = uilabel(app.Panel2);
            app.APILabel_5.Text = '25° API';
            app.APILabel_6 = uilabel(app.Panel2);
            app.APILabel_6.Text = '30° API';
            app.APILabel_7 = uilabel(app.Panel2);
            app.APILabel_7.Text = '35° API';
            app.APILabel_8 = uilabel(app.Panel2);
            app.APILabel_8.Text = '40° API';
            app.APILabel_9 = uilabel(app.Panel2);
            app.APILabel_9.Text = '45° API';
            app.adimLabel_2 = uilabel(app.Panel2);
            app.adimLabel_2.Text = 'adim';
            app.GradMEditFieldLabel = uilabel(app.Panel2);
            app.GradMEditFieldLabel.Text = 'Grad M ';
            app.GradMEditField = uieditfield(app.Panel2, 'text');
            app.ftLabel = uilabel(app.Panel2);
            app.ftLabel.Text = 'ft';
            app.NDEditFieldLabel = uilabel(app.Panel2);
            app.NDEditFieldLabel.Text = 'N.D ';
            app.NDEditField = uieditfield(app.Panel2, 'text');
            app.UIAxes = uiaxes(app.Panel2);
            xlabel(app.UIAxes, '%  agua salada')
            ylabel(app.UIAxes, 'Grad M')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.Panel3 = uipanel(app.BombeoNeumticoPanel);
            app.Panel3.Title = 'Panel3';
            app.psi1000ftLabel = uilabel(app.Panel3);
            app.psi1000ftLabel.Text = 'psi/1000 ft';
            app.pleidaEditFieldLabel = uilabel(app.Panel3);
            app.pleidaEditFieldLabel.Text = 'Δp leida';
            app.pleidaEditField = uieditfield(app.Panel3, 'text');
            app.FLabel_4 = uilabel(app.Panel3);
            app.FLabel_4.Text = '°F';
            app.TCalcEditFieldLabel = uilabel(app.Panel3);
            app.TCalcEditFieldLabel.Text = 'T Calc';
            app.TCalcEditField = uieditfield(app.Panel3, 'text');
            app.FLabel_5 = uilabel(app.Panel3);
            app.FLabel_5.Text = '°F';
            app.TpromEditFieldLabel = uilabel(app.Panel3);
            app.TpromEditFieldLabel.Text = 'T prom';
            app.TpromEditField = uieditfield(app.Panel3, 'text');
            app.psi1000ftLabel_2 = uilabel(app.Panel3);
            app.psi1000ftLabel_2.Text = 'psi/1000 ft';
            app.pcorreEditFieldLabel = uilabel(app.Panel3);
            app.pcorreEditFieldLabel.Text = 'Δp corre';
            app.pcorreEditField = uieditfield(app.Panel3, 'text');
            app.psi1000ftLabel_3 = uilabel(app.Panel3);
            app.psi1000ftLabel_3.Text = 'psi/1000 ft';
            app.pleida2EditFieldLabel = uilabel(app.Panel3);
            app.pleida2EditFieldLabel.Text = 'Δp leida 2';
            app.pleida2EditField = uieditfield(app.Panel3, 'text');
            app.psi1000ftLabel_4 = uilabel(app.Panel3);
            app.psi1000ftLabel_4.Text = 'psi/1000 ft';
            app.pcorre2EditFieldLabel = uilabel(app.Panel3);
            app.pcorre2EditFieldLabel.Text = 'Δp corre 2';
            app.pcorre2EditField = uieditfield(app.Panel3, 'text');
            app.GG10Label = uilabel(app.Panel3);
            app.GG10Label.Text = 'GG 1.0';
            app.GGGasGravityLabel = uilabel(app.Panel3);
            app.GGGasGravityLabel.Text = 'GG=Gas Gravity';
            app.GG09Label = uilabel(app.Panel3);
            app.GG09Label.Text = 'GG 0.9';
            app.GG08Label = uilabel(app.Panel3);
            app.GG08Label.Text = 'GG 0.8';
            app.GG07Label = uilabel(app.Panel3);
            app.GG07Label.Text = 'GG 0.7';
            app.GG065Label = uilabel(app.Panel3);
            app.GG065Label.Text = 'GG 0.65';
            app.GG06Label = uilabel(app.Panel3);
            app.GG06Label.Text = 'GG 0.6';
            app.UIAxes_2 = uiaxes(app.Panel3);
            title(app.UIAxes_2, {''; ''})
            xlabel(app.UIAxes_2, 'Presión  [psi/1000 ft]')
            ylabel(app.UIAxes_2, 'P disp [psi]')
            zlabel(app.UIAxes_2, 'Z')
            app.LneasDeGradientePanel = uipanel(app.BombeoNeumticoPanel);
            app.LneasDeGradientePanel.Title = 'Líneas De Gradiente';
            app.UITable = uitable(app.LneasDeGradientePanel);
            app.UITable.ColumnName = {'Profundidad [ft]'; 'P. Operación [psi]'; 'P. Disponible [psi]'};
            app.UITable.RowName = {};
            app.CalibracinDeVlvulasPanel = uipanel(app.BombeoNeumticoPanel);
            app.CalibracinDeVlvulasPanel.Title = 'Calibración De Válvulas';
            app.UIAxes2 = uiaxes(app.CalibracinDeVlvulasPanel);
            xlabel(app.UIAxes2, 'Presión [ psi ]')
            ylabel(app.UIAxes2, 'Profundudad [ ft ]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.XAxisLocation = 'top';
            app.UIAxes3 = uiaxes(app.CalibracinDeVlvulasPanel);
            xlabel(app.UIAxes3, 'Temperatura [ °F ]')
            ylabel(app.UIAxes3, 'Profundudad [ ft ]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YDir = 'reverse';
            app.UIAxes3.XAxisLocation = 'top';
            app.ResultadosDeLasGrficasPanel = uipanel(app.BombeoNeumticoPanel);
            app.ResultadosDeLasGrficasPanel.Title = 'Resultados De Las Gráficas';
            app.UITable2 = uitable(app.ResultadosDeLasGrficasPanel);
            app.UITable2.ColumnName = {'No. Válvula'; 'Prof [ ft ]'; 'Temp [ °F ]'; 'Pt diseño [ psi ]'; 'Pt flu [ psi ]'; 'Pso diseño [ psi ]'};
            app.UITable2.RowName = {};
            app.UITable2_3 = uitable(app.ResultadosDeLasGrficasPanel);
            app.UITable2_3.ColumnName = {'ΔP leida [psi/1000ft]'; 'T cal [°F]'; 'T real [°F]'; 'ΔP Correg [psi]'; 'Pvo diseño[psi]'};
            app.UITable2_3.RowName = {};
            app.Panel = uipanel(app.BombeoNeumticoPanel);
            app.LneadelgradienteDelFluidoDeControlPanel = uipanel(app.BombeoNeumticoPanel);
            app.LneadelgradienteDelFluidoDeControlPanel.Title = 'Línea del gradiente Del Fluido De Control';
            app.psiftLabel = uilabel(app.LneadelgradienteDelFluidoDeControlPanel);
            app.psiftLabel.Text = 'psi/ft';
            app.grafFCEditFieldLabel = uilabel(app.LneadelgradienteDelFluidoDeControlPanel);
            app.grafFCEditFieldLabel.Text = 'graf FC';
            app.grafFCEditField = uieditfield(app.LneadelgradienteDelFluidoDeControlPanel, 'text');
            app.UITable3 = uitable(app.LneadelgradienteDelFluidoDeControlPanel);
            app.UITable3.ColumnName = {'Produndidad [ ft ]'; 'Presión [ psi ]'};
            app.UITable3.RowName = {};
            app.LosvaloresseencuentranenelgrficoconunaxLabel = uilabel(app.LneadelgradienteDelFluidoDeControlPanel);
            app.LosvaloresseencuentranenelgrficoconunaxLabel.Text = 'Los valores se encuentran en el gráfico con una x';
            app.BombeoNeumticoLabel = uilabel(app.BombeoNeumticoPanel);
            app.BombeoNeumticoLabel.Text = 'Bombeo Neumático';
            app.CalculodelGastodelGasPanel = uipanel(app.BombeoNeumticoPanel);
            app.CalculodelGastodelGasPanel.Title = 'Calculo del Gasto del Gas';
            app.UITable2_4 = uitable(app.CalculodelGastodelGasPanel);
            app.UITable2_4.ColumnName = {'Pd@T.V [psi]'; 'Psc[psi]'; 'Pd @ 60 [°F] [psi]'; 'Ptro [psi]'; 'Pvo real [psi]'; 'Pso real [psi]'};
            app.UITable2_4.RowName = {};
            app.FLabel_6 = uilabel(app.CalculodelGastodelGasPanel);
            app.FLabel_6.Text = '250 °F';
            app.FLabel_7 = uilabel(app.CalculodelGastodelGasPanel);
            app.FLabel_7.Text = '200° F';
            app.FLabel_8 = uilabel(app.CalculodelGastodelGasPanel);
            app.FLabel_8.Text = '150° F';
            app.FLabel_9 = uilabel(app.CalculodelGastodelGasPanel);
            app.FLabel_9.Text = '100° F';
            app.FLabel_10 = uilabel(app.CalculodelGastodelGasPanel);
            app.FLabel_10.Text = '50° F';
            app.pie3diaLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.pie3diaLabel.Text = 'pie3/dia';
            app.qgiEditFieldLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.qgiEditFieldLabel.Text = 'qgi';
            app.qgiEditField = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.GastodegasdeinyeccinLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.GastodegasdeinyeccinLabel.Text = 'Gasto de gas de inyección';
            app.FactordecorrecindelgastodegasdeinyecLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.FactordecorrecindelgastodegasdeinyecLabel.Text = {'Factor de correción del gasto de gas de inyec'; ''};
            app.adimLabel_3 = uilabel(app.CalculodelGastodelGasPanel);
            app.adimLabel_3.Text = 'adim';
            app.FCLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.FCLabel.Text = 'F.C.';
            app.FCEditField_2 = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.GastodegasdeinyeccincorregidoLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.GastodegasdeinyeccincorregidoLabel.Text = 'Gasto de gas de inyección corregido';
            app.pie3diaLabel_2 = uilabel(app.CalculodelGastodelGasPanel);
            app.pie3diaLabel_2.Text = 'pie3/dia';
            app.qgicEditFieldLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.qgicEditFieldLabel.Text = 'qgic';
            app.qgicEditField = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.FLabel_11 = uilabel(app.CalculodelGastodelGasPanel);
            app.FLabel_11.Text = '°F';
            app.TpiEditFieldLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.TpiEditFieldLabel.Text = 'Tpi';
            app.TpiEditField = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.kEditFieldLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.CdAEditFieldLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.CdAEditFieldLabel.Text = 'CdA';
            app.CdAEditField = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.DiametroEditFieldLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.DiametroEditFieldLabel.Text = 'Diametro ɸ';
            app.DiametroEditField = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.pgLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.pgLabel.Text = '/64pg';
            app.REditFieldLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.CalculodelGastodelGasPanel, 'text');
            app.DatosRelaciondeCaloresEspecficosLabel = uilabel(app.CalculodelGastodelGasPanel);
            app.DatosRelaciondeCaloresEspecficosLabel.Text = 'Datos Relacion de Calores Específicos';
            app.adimLabel_7 = uilabel(app.CalculodelGastodelGasPanel);
            app.adimLabel_7.Text = 'adim';
            app.UIAxes4 = uiaxes(app.CalculodelGastodelGasPanel);
            title(app.UIAxes4, 'Relación de Calores Específicos ')
            xlabel(app.UIAxes4, 'k')
            ylabel(app.UIAxes4, 'γg')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BNeumatico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end